classdef DFR_69_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DistributionNetworkReconfigurationwithGA69BusLabel  matlab.ui.control.Label
        BuiltbyRogerCorniandRandyLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UITable2                        matlab.ui.control.Table
        RandomPowerDataButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        EXITButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Load69BusDataButton             matlab.ui.control.Button
    end

 
    properties (Access = public)
    var=load ('IEEE33EdgeTable.mat')
    s_t;R;X;s;t;g 
    xdata1;xdata2
    vbp;Z0;Vol_0;Z1;tieSW;Plosskw;Qlosskw
    data1;data2;Lweights;Rweights;NetPLoss 
    s1;t1;u;tie;Z2;ZBrL0 
    s2;t2;R1;X1;Vol_1;pow_L1;pow_X1 
 %*********************************
        Raw_table
    end
    
    methods (Access = public)
        
%**************************************************************************
 %****************************Voltage_fun_span1****************************       
   % function [vbp,Plosskw,Qlosskw,Z0] = Voltage_fun_span1(app,xdata)
   function Voltage_fun_span1(app)       
MVAb=1000;
div=1000;

if app.xdata1==1
    nf=app.data1(:,1);
    nt=app.data1(:,2);
    R_1=app.data1(:,3);
    Xl_1=app.data1(:,4);
    L_Act=[0; app.data1(:,5)];
    L_Reat=[0; app.data1(:,6)];
elseif app.xdata2==2
    nf=app.data2(:,1);
    nt=app.data2(:,2);
    R_1=app.data2(:,3);
    Xl_1=app.data2(:,4);
    L_Act=[0; app.data2(:,5)];
    L_Reat=[0; app.data2(:,6)];
end

br=length(R_1);
no=length(L_Act);

MVAb=100;%base MVA
KVb=11;% 11KV base
Zb=(KVb^2)/MVAb;% base impedance

for i=1:br
    R(i,1)= R_1(i)/Zb;
    Xl(i,1)=Xl_1(i)/Zb;
end
for i=1:no
    P(i,1)=L_Act(i)/(1000*MVAb);
    Q(i,1)=L_Reat(i)/(1000*MVAb);
    %Qsh(i,1)=((loaddata(i,4))/(1000*MVAb));   
end

R;% line resistance
Xl;% line reactance
j=sqrt(-1);
Z=R+j*Xl;% line impedance
P;% load active power
Q;% load reactive power
Pt=sum(P);% total active power 
Qt=sum(Q);% total reactive power
%Pt
%Qt
C=zeros(br,no);% initilization for zero matrix
for i=1:br
    a=nf(i);
    b=nt(i);
    for j=1:no
        if a==j
            C(i,j)=-1;
        end
        if b==j
            C(i,j)=1;
        end
    end
end
C;
e=1;
for i=1:no
    d=0;
    for j=1:br
        if C(j,i)==-1
            d=1;
        end
    end
    if d==0
        endnode(e,1)=i;
        e=e+1;
    end
end
endnode;
h=length(endnode);
for j=1:h
    e=2;
    
    f=endnode(j,1);
   % while (f~=1)
   for s=1:no
     if (f~=1)
       k=1;  
       for i=1:br
           if ((C(i,f)==1)&&(k==1))
                f=i;
                k=2;
           end
       end
       k=1;
       for i=1:no
           if ((C(f,i)==-1)&&(k==1));
                f=i;
                g(j,e)=i;
                e=e+1;
                k=3;
           end            
       end
     end
   end
end
for i=1:h
    g(i,1)=endnode(i,1);
end
g;
w=length(g(1,:));
for i=1:h
    j=1;
    for k=1:no 
        for ts=1:w
            if g(i,ts)==k
                g(i,ts)=g(i,j);
                g(i,j)=k;
                j=j+1;
             end
         end
    end
end
g;
for k=1:br
    e=1;
    for i=1:h
        for j=1:w-1
            if (g(i,j)==k) 
                if g(i,j+1)~=0
                    adjb(k,e)=g(i,j+1);            
                    e=e+1;
                else
                    adjb(k,1)=0;
                end
             end
        end
    end
end
adjb;
for i=1:br-1
    for j=h:-1:1
        for k=j:-1:2
            if adjb(i,j)==adjb(i,k-1)
                adjb(i,j)=0;
            end
        end
    end
end
adjb;
x=length(adjb(:,1));
ab=length(adjb(1,:));
for i=1:x
    for j=1:ab
        if adjb(i,j)==0 && j~=ab
            if adjb(i,j+1)~=0
                adjb(i,j)=adjb(i,j+1);
                adjb(i,j+1)=0;
            end
        end
        if adjb(i,j)~=0
            adjb(i,j)=adjb(i,j)-1;
        end
    end
end
adjb;
for i=1:x-1
    for j=1:ab
        adjcb(i,j)=adjb(i+1,j);
    end
end
b=length(adjcb);

% voltage current program

for i=1:no
    vb(i,1)=1;
end
for ss=1:10
for i=1:no
    nlc(i,1)=conj(complex(P(i,1),Q(i,1)))/(vb(i,1));
end
nlc;
for i=1:br
    Ibr(i,1)=nlc(i+1,1);
end
Ibr;
xy=length(adjcb(1,:));
for i=br-1:-1:1
    for k=1:xy
        if adjcb(i,k)~=0
            u=adjcb(i,k);
            %Ibr(i,1)=nlc(i+1,1)+Ibr(k,1);
            Ibr(i,1)=Ibr(i,1)+Ibr(u,1);
        end
    end      
end
Ibr;
for i=2:no
      g=0;
      for a=1:b 
          if xy>1
            if adjcb(a,2)==i-1 
                u=adjcb(a,1);
                vb(i,1)=((vb(u,1))-((Ibr(i-1,1))*(complex((R(i-1,1)),Xl(i-1,1)))));
                g=1;
            end
            if adjcb(a,3)==i-1 
                u=adjcb(a,1);
                vb(i,1)=((vb(u,1))-((Ibr(i-1,1))*(complex((R(i-1,1)),Xl(i-1,1)))));
                g=1;
            end
          end
        end
        if g==0
            vb(i,1)=((vb(i-1,1))-((Ibr(i-1,1))*(complex((R(i-1,1)),Xl(i-1,1)))));
        end
end
ss=ss+1;
end
nlc;
Ibr;
vb;
app.vbp=[abs(vb) angle(vb)*180/pi];

for i=1:no
    va(i,2:3)=app.vbp(i,1:2);
end
for i=1:no
    va(i,1)=i;
end
va;


Ibrp=[abs(Ibr) angle(Ibr)*180/pi];
PL(1,1)=0;
QL(1,1)=0;

% losses
for f=1:br
    Pl(f,1)=(Ibrp(f,1)^2)*R(f,1);
    Ql(f,1)=Xl(f,1)*(Ibrp(f,1)^2);
    PL(1,1)=PL(1,1)+Pl(f,1);
    QL(1,1)=QL(1,1)+Ql(f,1);
end

app.Plosskw=(Pl)*100000;
app.Qlosskw=(Ql)*100000;
PL=(PL)*100000;
QL=(QL)*100000;

voltage = app.vbp(:,1);
ang = app.vbp(:,2)*(pi/180);

for i=1:no
    if i==1
        Pg(i,1)=(Pt*100000)+PL;
        Qg(i,1)=(Qt*100000)+QL;
    else
        Pg(i,1)=0;
        Qg(i,1)=0;
    end
end

                       %display the power flow results
Vm=voltage';deltad=ang';Pgen=Pg';Qgen=Qg';Pd=P*100000';Qd=Q*100000';%Qinj=Qsh';

Pdt=sum(Pd);Qdt=sum(Qd);Pgent=sum(Pg);Qgent=sum(Qg);%Qinjt=sum(Qsh);


SLT = 0;
g=1;
for n = 1:no
busprt = 0;
   for L = 1:br;
       if busprt == 0

       busprt = 1;
       else
       end
       if nf(L)==n     k = nt(L);
       In = (Vm(n) - Vm(k))/Z(L);
       Ik = (Vm(k) - Vm(n))/Z(L);
       Snk = Vm(n)*conj(In)*100000;
       Skn = Vm(k)*conj(Ik)*100000;
       SL  = Snk + Skn;
       SLT = SLT + SL;
       elseif nt(L)==n  k = nf(L);
       In = (Vm(n) - Vm(k))/Z(L);
       Ik = (Vm(k) - Vm(n))/Z(L);
       Snk = Vm(n)*conj(In)*100000;
       Skn = Vm(k)*conj(Ik)*100000;
       SL  = Snk + Skn;
       SLT = SLT + SL;
       else
       end
         if nf(L)==n || nt(L)==n
         Knew=k;
          id(g,:)=[n Knew real(SL) imag(SL) Vm(n)];
         g=g+h;
         else
         end
  end
end
id(~any(id'),:)=[]; % Remove emply rows from Power data array
A=id; %[ActiveNLoad React.Nload ActiveLineLoss ReactiveLineLoss]   
[C,ia] = unique(A(:,4));
app.Z0=A(ia,:);
    end   
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load69BusDataButton
        function LoadDataButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.xls'}, 'File Browser');
             fullpathname=strcat(pathname, filename);
            app.data1=readtable(filename,"Sheet",1);
            app.UITable.Data=app.data1;
            app.UITable.ColumnName=app.data1.Properties.VariableNames;
            app.UITable.ColumnName=app.data1.Properties.VariableNames;
            
            uit=app.UITable; 
            su = uistyle; 
            su.HorizontalAlignment = 'center'; 
            addStyle(uit,su); 
        end

        % Callback function: RunButton, UITable2, UITable2, UITable2
        function RunButtonPushed(app, event)
%************************Prediction run************************************                 
%           app.fig = uifigure;
%           app.dep = uiprogressdlg(app.fig,'Title','Predicting',...
%         'Indeterminate','on');
%          drawnow
%          close(app.dep)
%          delete(app.fig)
         
        app.tie=[[11 43];[13 21];[15 46];[50 59];[65 27] ];  
        app.tieSW=[69; 70; 71;72;73];
        [m, n]=size(app.tie); 
       app.Lweights= table2array(app.UITable.Data(1:68,2));
       app.Rweights= table2array(app.UITable.Data(1:68,3));
       
       app.s= table2array(app.UITable.Data(1:68,10));
       app.t= table2array(app.UITable.Data(1:68,11));
       app.R= table2array(app.UITable.Data(1:68,12));
       app.X= table2array(app.UITable.Data(1:68,13));
       
       app.data1=[app.s app.t app.R app.X app.Lweights app.Rweights]; 
       st0 = [app.s, app.t];
        app.xdata1 = 1;
        app.xdata2=0;
      % [app.Vol_0,app.pow_L1,app.pow_X1,app.Z1]=Voltage_fun_span1(app.data1);
       Voltage_fun_span1(app);
       app.Z1=app.Z0;
       app.Vol_0=app.vbp;
       
       app.NetPLoss=sum(app.Plosskw); % Net Real Power loss of IEEE33 network
       NetVol=sum(app.Z1(:,5));

      % *******************GA Function*************************************
       numberOfVariables = 5;
       rng(1,'twister')
       IntCon = 1:5;
       lb=[0 0 0 0 0];
       ub=[1 1 1 1 1];
      % app.x=[0 0 0 0 0];
       options = gaoptimset('TolFun',1e-12,'Generations',10  ,'Vectorized',...
       'off','PopulationSize',10, 'TolCon',1e-12,'Display','off',...
       'OutputFcn',@gaoutfun_span);
    [x,fval]=ga(@Voltage_fun_span_optim,numberOfVariables,[],[],[],[], ...
         lb,ub,[],IntCon,options); 
   
  %*****************************Voltage_fun_span_optim*********************
  %************************************************************************
  function err = Voltage_fun_span_optim(x)
 
% global data1 NetPLoss s1 t1 Z1 u g tie Z2  ZBrL0 
% global  s2 t2 R1 X1 Vol_1 pow_L1 pow_X1 data2 x0 

  x0=x';
  x2f=x0.*app.tie(:,:);
  g1=x2f(:,1);
  g2=x2f(:,2);
  app.g=[g1(g1~=0) g2(g2~=0)];
  u = app.g; % Otimized tie SW
  s_t = [app.data1(:,1), app.data1(:,2)];
  st1=[s_t;app.g]; %Add tie switch data to IEEE 33 data
  s1=st1(:,1);
  t1=st1(:,2);
  R=app.data1(:,3);
  X=app.data1(:,4);

  ZBrL0=app.Z1(:,3); %sqrt(Z1(:,3).^2+Z1(:,4).^2);
 W1=round([ ZBrL0; rand(numel(app.g(:,1)),1)],2); % tie swt random
%*********************plot graph with tie cycle switches******************
G = graph(s1,t1);
G.Edges.Weight=W1;
%*************Plot Minimum Tree with Krushal criteria********************** 
[T,pred] = minspantree(G,'Method','sparse');

rootedTree = digraph(pred(pred~=0),find(pred~=0),[]);

%*******************Minimum Tree Egdes data********************************
TE=rootedTree.Edges{:,1}; % change Table to array
s2=TE(:,1);
t2=TE(:,2);
netdata0=[s2, t2];
netdata0=sortrows(netdata0,2);

%********Inserting tie line data to Min. Span Tree Edge data***************
v=length(app.g(:,1));
for y=1:v
    f0=ismember(netdata0,app.g(y,:));
    f1=find(f0(:,1)&f0(:,2)==1);  
        R(f1,1)=2;
        X(f1,2)=2;
end

R1=R;
X1=X;

 %************************Network Power Loss and Voltage****************
 netWt=[app.data1(:,5) app.data1(:,6)];
 data2=[netdata0(:,1) netdata0(:,2) R1 X1 netWt(:,1) netWt(:,2)]; 
[app.Vol_1,app.pow_L1,app.pow_X1,Z2]=Voltage_fun_span1(data2);
err= (sum(Z2(:,3)-app.NetPLoss));%+sum(Vol_1(:,1)-15));%15
    end
  
%**************************************************************************    
%*****************************gaoutfun_span*******************************
    function [state,options,optchanged] = gaoutfun_span(options,state,flag)
%  global R X Vol_1 pow_L1 tie 
%  global Z1 g data1 tieSW
%  global s t Rweights Lweights 
 [m n]=size(app.tie);
  
optchanged = false;
switch flag
    case 'init'
        
    case {'iter','interrupt'}
         
    case 'done'
     
% %********plot Optimized IEEE33 graph with tie cycle switches*************

  s_t = [app.data1(:,1), app.data1(:,2)];
  st1=[s_t;app.g]; %Add tie switch data to IEEE 33 data
  s11=st1(:,1);
  t11=st1(:,2);
  app.R=app.data1(:,3);
  app.X=app.data1(:,4);

  ZBrL0=app.Z1(:,3); %sqrt(Z1(:,3).^2+Z1(:,4).^2);
 W=round([ ZBrL0; rand(numel(app.g(:,1)),1)],2); % tie swt random
 
%*********************plot graph with tie cycle switches******************
G = graph(s11,t11);
G.Edges.Weight=W;
figure (2)
subplot(121)
p = plot(G);
p.NodeFontSize = 7;
p.EdgeFontSize=7;
title('Optimized IEEE69 Network Graph with Tie SW')
%********Plot Optim.Minimum Tree with Krushal criteria*********************

 [T,pred] = minspantree(G,'Method','sparse');
 p.MarkerSize =2;
 highlight(p,T);
 ni=find(ismember(G.Edges(:,1:2),T.Edges(:,1:2))==0);
for y=1:numel(ni)
 highlight(p,'Edges',ni(y,1),'EdgeColor','r');
 df=table2array(G.Edges(ni(y,1),:));
 highlight(p,df(1,1:2),'NodeColor','r');
end
 rootedTree = digraph(pred(pred~=0),find(pred~=0),[]);
TE=rootedTree.Edges{:,1};  
s3=TE(:,1);
t3=TE(:,2); 
W=round(app.ZBrL0,2);
G=graph(s3,t3);
figure (2)
subplot(122)
G.Edges.Weight=ones(numel(s3),1);
%P = plot(G);
P = plot(G,'LineWidth',G.Edges.Weight)
P.NodeFontSize = 6;
P.EdgeFontSize=7;
title('Optimized IEEE69 Net. Span Tree with Tie SW')
O_E=table2array(G.Edges(:,1));

%******************IEEE 33 Data for power flow Analysis********************
   s_t=[app.s app.t];
   app.data1=[app.s app.t app.R app.X app.Lweights app.Rweights]; 
   app.xdata1 = 1;
   app.xdata2=0;
   Voltage_fun_span1(app);
  app.Vol_0 = app.vbp;
  pow_L0= app.Plosskw;
  pow_X0= app.Qlosskw;
  Z =     app.Z0;
%**********************Plot IEEE33 Power flow Results**********************
  figure (1)
  subplot(211),plot(1:numel(app.Vol_1(:,1)),app.Vol_1(:,1),'r',...
      1:numel(app.Vol_0(:,1)),app.Vol_0(:,1),'--')
  legend( 'Optimized', 'Base Case','location', 'southwest')
   title('IEEE69 Voltage profile in p.u.')
   xlabel('Node')
   ylabel('p.u.')
  grid
  subplot(212),plot(1:numel(app.pow_L1(:,1)),app.pow_L1(:,1),'r', ...
      1:numel(pow_L0(:,1)),pow_L0(:,1),'--')
  sum1=sum(app.pow_L1);
  sum0=sum(pow_L0);
  legend( 'Optimized', 'Base Case','location', 'northeast')
  %title('IEEE33 Br. Power Losses')
  title({'IEEE69 Br. Power Losses ';['Base Case ',num2str(sum0),'KW'...
      '  Optim. Loss ',num2str(sum1),'KW']})
   xlabel('Node')
   ylabel('Power Loss KW')
  grid  
  
 %*************Plot IEEE33 Standard Span Tree******************************
  weights= Z(:,3);%sqrt(Z(:,3).^2 + Z(:,4).^2); % Power loss weights
   G = graph(app.s,app.t);
   G.Edges.Weight=round(weights,2);
  figure (3)
  subplot(121)
  %p = plot(G,'EdgeLabel',G.Edges.Weight);
  p = plot(G);
  title('IEEE69 Network Span Tree')
  S_E=[app.s app.t];
  E_str1 = string([app.s, app.t]);
  E_str=string([E_str1;app.tie(1:m,1:n)]);
 edgesUnique = cellstr(unique(E_str, 'rows'));
 index = find(strcmp(edgesUnique(:,1), edgesUnique(:,2)));
 if index~=0
 edgesUnique(index,:)=[];
 end
 s2=E_str(:,1);
 t2=E_str(:,2);

  weights_L=[ weights; rand(numel(app.tie(:,1)),1)]; %Power L.wts + tie dummy
 
 %**************Searching for sectional OFF switches********************** 
  comp=~ismember(S_E,O_E,'rows');
  stnd=S_E(comp,1)';
  %**************Searching for Tie ON/OFF switches************************* 
 % tieSWOFF=find(ismember(S_E,O_E,'rows')==0)
  tieSWOpen=app.tieSW(find(ismember(app.tie,app.g,'rows')));
  tieSWClosed=app.tieSW(find(~ismember(app.tie,app.g,'rows')));
  
 %******************Plot IEEE33 Graph with All Tie switches**************** 
%app.s_t=app.var;
app.s_t=app.var.IEEE33EdgeTable;
Edge_L = string(app.s_t(:,3));
EdgeTable = table([app.s_t(:,1) app.s_t(:,2)],Edge_L, ...
    'VariableNames',{'EndNodes' 'Edge_L'});
Nodes = string(1:33)';
NodeTable = table(Nodes);
G =   graph(EdgeTable,NodeTable);
 figure (3)
 subplot(122)
p1=plot(G,'NodeLabel',G.Nodes.Nodes,'EdgeLabel',G.Edges.Edge_L)
p1.NodeFontSize = 6;
p1.EdgeFontSize=7;
title('IEEE69 Network Graph with All SWs ON')

 %************************Printing Results*********************************
SWsum=[stnd';tieSWClosed];
app.UITable2.Data(1:numel(tieSWOpen),1)=tieSWOpen;
app.UITable2.Data(1:numel(SWsum),2)=SWsum;    
            uit2=app.UITable2; 
            su2 = uistyle; 
            su2.HorizontalAlignment = 'center'; 
             addStyle(uit2,su2);
             
app.UITable.Data(1:length(app.vbp),7)=array2table(app.vbp(:,1));             
app.UITable.Data(1:length(app.vbp),8)=array2table(app.vbp(:,2));              

end
    end
        
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
clf
msgbox('Closing App. - Thankyou')
pause(0)
close()
close all force
        end

        % Button pushed function: RandomPowerDataButton
        function RandomPowerDataButtonPushed(app, event)
            
            Lweights1= table2array(app.UITable.Data(1:68,2));
            LW=Lweights1(2:length(Lweights1));
            app.Lweights = [0 LW(randperm(length(LW)))']';
            app.UITable.Data(1:68,2)= array2table(app.Lweights)
            
            Rweights1= table2array(app.UITable.Data(1:33,3));
            RW=Rweights1(2:length(Rweights1));     
           app.Rweights = [0 RW(randperm(length(RW)))']';
            app.UITable.Data(1:33,3)= array2table(app.Rweights) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Load69BusDataButton = uibutton(app.UIFigure, 'push');
            app.Load69BusDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.Load69BusDataButton.Text = {'Load 69 Bus '; 'Data'};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run '; ''};
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RandomPowerDataButton = uibutton(app.UIFigure, 'push');
            app.RandomPowerDataButton.ButtonPushedFcn = createCallbackFcn(app, @RandomPowerDataButtonPushed, true);
            app.RandomPowerDataButton.Text = {'  Random '; 'Power Data'};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Tie SW Closed'; 'SW Open'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @RunButtonPushed, true);
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @RunButtonPushed, true);
            app.UITable2.DisplayDataChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IEEE69 BUS.jpg';
            app.BuiltbyRogerCorniandRandyLabel = uilabel(app.UIFigure);
            app.BuiltbyRogerCorniandRandyLabel.Text = {'By:  Roger Wienaah'; '         Cornelius Appiah'; '           Ansah Kofi Ansah'; ''; 'Supervisor: Ing. Kwabena A Kyeremeh'};
            app.DistributionNetworkReconfigurationwithGA69BusLabel = uilabel(app.UIFigure);
            app.DistributionNetworkReconfigurationwithGA69BusLabel.Text = 'Distribution Network Reconfiguration with GA - 69 Bus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DFR_69_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end